classdef Shtatistics_Table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TableUIFigure      matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        EditMenu           matlab.ui.container.Menu
        ViewMenu           matlab.ui.container.Menu
        WindowMenu         matlab.ui.container.Menu
        HelpMenu           matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        UITable2           matlab.ui.control.Table
        UITable2_2         matlab.ui.control.Table
        GridLayout5        matlab.ui.container.GridLayout
        NewFilterButton    matlab.ui.control.Button
        NewVariableButton  matlab.ui.control.Button
        GridLayout6        matlab.ui.container.GridLayout
        Image2             matlab.ui.control.Image
        RightPanel         matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        UITable            matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CallingApp
        NewFilterApp
        
        TableData
    end
    
    methods (Access = public)
        
        function Close(app)
            app.TableUIFigureCloseRequest
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.CallingApp = CallingApp;
        end

        % Button pushed function: NewFilterButton
        function NewFilterButtonPushed(app, event)
            app.NewFilterApp = Shtatistics_NewFilter;
        end

        % Close request function: TableUIFigure
        function TableUIFigureCloseRequest(app, event)
            if ~isempty(app.NewFilterApp)
                app.NewFilterApp.NewFilterUIFigureCloseRequest
            end
            % Notify the calling app it's being closed.
            app.delete
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            [fileName, path] = uigetfile({'*.xls;*.xlsx', 'Excel Files (*.xls,*.xlsx)';...
                '*.*', 'All Files (*.*)'});
            app.TableData = xlsread([path '/' fileName]);     %%%%%
            app.UITable.Data = app.TableData;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TableUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {352, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TableUIFigure = uifigure('Visible', 'off');
            app.TableUIFigure.Name = 'Table';
            app.TableUIFigure.CloseRequestFcn = createCallbackFcn(app, @TableUIFigureCloseRequest, true);
            app.TableUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.TableUIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.TableUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.TableUIFigure);
            app.ViewMenu.Text = 'View';
            app.WindowMenu = uimenu(app.TableUIFigure);
            app.WindowMenu.Text = 'Window';
            app.HelpMenu = uimenu(app.TableUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.TableUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UITable2 = uitable(app.GridLayout2);
            app.UITable2.ColumnName = {'Class'; 'Name'; 'Size'; 'Value'; 'Notes'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = true;
            app.UITable2_2 = uitable(app.GridLayout2);
            app.UITable2_2.ColumnName = {'Active'; 'Name'; 'Notes'};
            app.UITable2_2.RowName = {''};
            app.UITable2_2.ColumnSortable = true;
            app.UITable2_2.ColumnEditable = [false true true];
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.NewFilterButton = uibutton(app.GridLayout5, 'push');
            app.NewFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NewFilterButtonPushed, true);
            app.NewFilterButton.Text = 'New Filter';
            app.NewVariableButton = uibutton(app.GridLayout5, 'push');
            app.NewVariableButton.Text = 'New Variable';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.Image2 = uiimage(app.GridLayout6);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Tooltip = {'Load different Excel spreadsheet'};
            app.Image2.ImageSource = 'excel_office_4658.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.TableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shtatistics_Table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TableUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TableUIFigure)
        end
    end
end