classdef Shtatistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShtatisticsUIFigure    matlab.ui.Figure
        ShtatisticsMenu        matlab.ui.container.Menu
        FileMenu               matlab.ui.container.Menu
        NewMenu                matlab.ui.container.Menu
        OpenMenu               matlab.ui.container.Menu
        SaveMenu               matlab.ui.container.Menu
        SaveAsMenu             matlab.ui.container.Menu
        ExitShtatisticsMenu    matlab.ui.container.Menu
        EditMenu               matlab.ui.container.Menu
        ViewMenu               matlab.ui.container.Menu
        FullScreenMenu         matlab.ui.container.Menu
        WindowMenu             matlab.ui.container.Menu
        MainWindowMenu         matlab.ui.container.Menu
        OpenTableMenu          matlab.ui.container.Menu
        HelpMenu               matlab.ui.container.Menu
        AboutShtatisticsMenu   matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        AddFigureButton        matlab.ui.control.Button
        AddPlotButton          matlab.ui.control.Button
        Tree                   matlab.ui.container.Tree
        Figure1Node            matlab.ui.container.TreeNode
        Plot1Node              matlab.ui.container.TreeNode
        Plot2Node              matlab.ui.container.TreeNode
        Plot3Node              matlab.ui.container.TreeNode
        Figure2Node            matlab.ui.container.TreeNode
        Plot1Node_2            matlab.ui.container.TreeNode
        Plot2Node_2            matlab.ui.container.TreeNode
        WorkspaceUITable       matlab.ui.control.Table
        GridLayout5            matlab.ui.container.GridLayout
        ShtatisticsLabel       matlab.ui.control.Label
        GridLayout6            matlab.ui.container.GridLayout
        RefreshWrkspaceButton  matlab.ui.control.Button
        OpenTableButton        matlab.ui.control.Button
        ImportButton           matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CalledApps = struct
    end
    
    properties (Dependent)
        Whos
        WorkspaceVars
    end
    
    methods   % set / get methods for dependent properties
        
        function value = get.Whos(~)
            value = evalin("base", "whos");
        end
        
        function value = get.WorkspaceVars(app)   % might be faster to set as regular property
            if ~isempty(app.Whos)
                value = struct('Name', '', 'Class', '', 'Size', [], 'Bytes', [], 'Value', []);
                for r = 1 : length(app.Whos)
                    value(r).Name  = app.Whos(r).name;
                    value(r).Class = app.Whos(r).class;
                    value(r).Size  = app.Whos(r).size;
                    value(r).Bytes = app.Whos(r).bytes;
                    value(r).Value = evalin("base", app.Whos(r).name);
                end
            else
                value = struct([]);
            end
        end
        
    end
    
    events
        
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RefreshWrkspaceButtonPushed()
        end

        % Menu selected function: ExitShtatisticsMenu
        function ExitShtatisticsMenuSelected(app, event)
            app.ShtatisticsUIFigureCloseRequest()
        end

        % Button pushed function: OpenTableButton
        function OpenTableButtonPushed(app, event)
            app.CalledApps.Table = Shtatistics_Table(app);
        end

        % Close request function: ShtatisticsUIFigure
        function ShtatisticsUIFigureCloseRequest(app, event)
            k = fieldnames(app.CalledApps);
            if ~isempty(k)
                for i = 1 : length(k)
                    eval(['app.CalledApps.' k{i} '.delete'])
                end
            end
            delete(app)
        end

        % Button pushed function: AddFigureButton
        function AddFigureButtonPushed(app, event)
            app.Tree.Children(end+1) = uitreenode(app.Tree);
            app.Tree.Children(end).Text = ['Figure ' num2str(length(app.Tree.Children))];
            app.Tree.SelectedNodes   = app.Tree.Children(end);
            app.TreeSelectionChanged()
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Button pushed function: AddPlotButton
        function AddPlotButtonPushed(app, event)
            if isempty(app.Tree.SelectedNodes)
                app.AddFigureButtonPushed()
            end
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes.Parent.Type == "uitree"
                % The selected node is a figure
                selectedNodes.Children(end+1) = uitreenode(selectedNodes);
                selectedNodes.Children(end).Text = ['Plot ' num2str(length(selectedNodes.Children))];
                selectedNodes.expand()
                app.Tree.SelectedNodes   = selectedNodes.Children(end);
            elseif selectedNodes.Parent.Parent.Type == "uitree"
                % The selected node is a plot
                parentFigure = selectedNodes.Parent;
                parentFigure.Children(end+1) = uitreenode(parentFigure);
                parentFigure.Children(end).Text = ['Plot ' num2str(length(parentFigure.Children))];
                parentFigure.expand()
                app.Tree.SelectedNodes   = parentFigure.Children(end);
            else
                error('Method app.AddPlotButtonPushed has run into an ERROR.')
            end
            app.TreeSelectionChanged()
        end

        % Button pushed function: RefreshWrkspaceButton
        function RefreshWrkspaceButtonPushed(app, event)
            oldData = app.WorkspaceUITable.Data;
            
            columns = string( app.WorkspaceUITable.ColumnName );
            app.WorkspaceUITable.Data = cell(length(app.Whos), length(columns));
            if ~isempty(app.WorkspaceVars)
                for r = 1 : length(app.WorkspaceVars)
                    if any(strcmpi(columns, "Class"))
                        app.WorkspaceUITable.Data{r, strcmpi(columns, "Class")} = app.WorkspaceVars(r).Class;
                    end
                    if any(strcmpi(columns, "Name"))
                        app.WorkspaceUITable.Data{r, strcmpi(columns, "Name")} = app.WorkspaceVars(r).Name;
                    end
                    if any(strcmpi(columns, "Bytes"))
                        app.WorkspaceUITable.Data{r, strcmpi(columns, "Bytes")} = app.WorkspaceVars(r).Bytes;
                    end
                    if any(strcmpi(columns, "Size"))
                        app.WorkspaceUITable.Data{r, strcmpi(columns, "Size")} = num2str(app.WorkspaceVars(r).Size(1));
                        for i = 2 : length(app.WorkspaceVars(r).Size)
                            app.WorkspaceUITable.Data{r, strcmpi(columns, "Size")} = [app.WorkspaceUITable.Data{r, strcmpi(columns, "Size")}, 'x', num2str(app.WorkspaceVars(r).Size(i))];
                        end
                    end
                    if any(strcmpi(columns, "Value"))
                        try
                            validateattributes(app.WorkspaceVars(r).Value, ["numeric", "logical", "char", "string"], {})
                            app.WorkspaceUITable.Data{r, strcmpi(columns, "Value")} = Matrix2ValueChar(app.WorkspaceVars(r));
                        catch ME
                            app.WorkspaceUITable.Data{r, strcmpi(columns, "Value")} = [app.WorkspaceUITable.Data{r, strcmpi(columns, "Size")} ' ' app.WorkspaceVars(r).Class];
                        end
                    end
                end
            else
                app.WorkspaceUITable.Data = {};
            end
            
            function value = Matrix2ValueChar(S)
                if (isnumeric(S.Value)) && (prod(S.Size) >= 1) && (prod(S.Size) <= 10)
                    str = string(S.Value);
                    tmp = join(str, ',', 2);
                    tmp = join(tmp, ';', 1);
                    value = char(tmp);
                elseif isscalar(S.Value)
                    value = char(S.Value);
                    switch S.Class
                        case "char"
                            value = ['''' value ''''];
                        case "string"
                            value = ['"' value '"'];
                    end
                else
                    size = num2str(S.Size(1));
                    for k = 2 : length(S.Size)
                        size = [size, 'x', num2str(S.Size(k))];
                    end
                    value = [size ' ' S.Class];
                end
            end
        end

        % Cell edit callback: WorkspaceUITable
        function WorkspaceUITableCellEdit(app, event)
            indices = event.Indices;
            oldData = event.PreviousData;
            newData = event.NewData;
            
            app.RefreshWrkspaceButtonPushed()
            app.WorkspaceUITable.Data{indices(1), indices(2)} = newData;
            switch app.WorkspaceUITable.ColumnName{indices(2)}
                case "Name"
                    if sum(strcmpi(newData, app.WorkspaceUITable.Data(:,indices(2)))) == 1   % Check that variable name is unique
                        assignin("base", newData, evalin("base", oldData))
                        evalin("base", ['clear ' oldData])
                    else
                        app.WorkspaceUITable.Data{indices(1), indices(2)} = oldData;
                        msg = sprintf("Couldn't change variable name from '%s' to '%s', since a varible of this name already exists.\n", oldData, newData);
                        warning(msg)
                    end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            uiimport('-file')
        end

        % Callback function
        function ImportSpreadsheetMenuSelected(app, event)
            app.ImportButtonPushed()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ShtatisticsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1080, 1080};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {448, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShtatisticsUIFigure = uifigure('Visible', 'off');
            app.ShtatisticsUIFigure.Name = 'Shtatistics';
            app.ShtatisticsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShtatisticsUIFigureCloseRequest, true);
            app.ShtatisticsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ShtatisticsMenu = uimenu(app.ShtatisticsUIFigure);
            app.ShtatisticsMenu.Text = 'Shtatistics';
            app.FileMenu = uimenu(app.ShtatisticsUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Separator = 'on';
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExitShtatisticsMenu = uimenu(app.FileMenu);
            app.ExitShtatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitShtatisticsMenuSelected, true);
            app.ExitShtatisticsMenu.Separator = 'on';
            app.ExitShtatisticsMenu.Accelerator = 'q';
            app.ExitShtatisticsMenu.Text = 'Exit Shtatistics';
            app.EditMenu = uimenu(app.ShtatisticsUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.ShtatisticsUIFigure);
            app.ViewMenu.Text = 'View';
            app.FullScreenMenu = uimenu(app.ViewMenu);
            app.FullScreenMenu.Text = 'Full Screen';
            app.WindowMenu = uimenu(app.ShtatisticsUIFigure);
            app.WindowMenu.Text = 'Window';
            app.MainWindowMenu = uimenu(app.WindowMenu);
            app.MainWindowMenu.Accelerator = '1';
            app.MainWindowMenu.Text = 'Main Window';
            app.OpenTableMenu = uimenu(app.WindowMenu);
            app.OpenTableMenu.Accelerator = '2';
            app.OpenTableMenu.Text = 'Open Table...';
            app.HelpMenu = uimenu(app.ShtatisticsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutShtatisticsMenu = uimenu(app.HelpMenu);
            app.AboutShtatisticsMenu.Text = 'About Shtatistics...';
            app.GridLayout = uigridlayout(app.ShtatisticsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.AddFigureButton = uibutton(app.GridLayout3, 'push');
            app.AddFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AddFigureButtonPushed, true);
            app.AddFigureButton.Text = 'Add Figure';
            app.AddPlotButton = uibutton(app.GridLayout3, 'push');
            app.AddPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlotButtonPushed, true);
            app.AddPlotButton.Text = 'Add Plot';
            app.Tree = uitree(app.GridLayout2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.Tree.Tooltip = {'Figures and plots'};
            app.Figure1Node = uitreenode(app.Tree);
            app.Figure1Node.Text = 'Figure 1';
            app.Plot1Node = uitreenode(app.Figure1Node);
            app.Plot1Node.Text = 'Plot 1';
            app.Plot2Node = uitreenode(app.Figure1Node);
            app.Plot2Node.Text = 'Plot 2';
            app.Plot3Node = uitreenode(app.Figure1Node);
            app.Plot3Node.Text = 'Plot 3';
            app.Figure2Node = uitreenode(app.Tree);
            app.Figure2Node.Text = 'Figure 2';
            app.Plot1Node_2 = uitreenode(app.Figure2Node);
            app.Plot1Node_2.Text = 'Plot 1';
            app.Plot2Node_2 = uitreenode(app.Figure2Node);
            app.Plot2Node_2.Text = 'Plot 2';
            app.WorkspaceUITable = uitable(app.GridLayout2);
            app.WorkspaceUITable.ColumnName = {'Class'; 'Name'; 'Size'; 'Value'};
            app.WorkspaceUITable.RowName = {};
            app.WorkspaceUITable.ColumnSortable = true;
            app.WorkspaceUITable.ColumnEditable = [false true false false];
            app.WorkspaceUITable.CellEditCallback = createCallbackFcn(app, @WorkspaceUITableCellEdit, true);
            app.WorkspaceUITable.Tooltip = {'Workspace'};
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.ShtatisticsLabel = uilabel(app.GridLayout5);
            app.ShtatisticsLabel.Text = 'Shtatistics';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.RefreshWrkspaceButton = uibutton(app.GridLayout6, 'push');
            app.RefreshWrkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshWrkspaceButtonPushed, true);
            app.RefreshWrkspaceButton.Icon = '585e4831cb11b227491c338e.png';
            app.RefreshWrkspaceButton.Tooltip = {'Refresh Workspace'};
            app.RefreshWrkspaceButton.Text = '';
            app.OpenTableButton = uibutton(app.GridLayout6, 'push');
            app.OpenTableButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTableButtonPushed, true);
            app.OpenTableButton.Icon = 'excel_office_4658.png';
            app.OpenTableButton.Text = '';
            app.ImportButton = uibutton(app.GridLayout6, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = '150_-_Communication_import_add_down_import_icon-512.png';
            app.ImportButton.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            % Show the figure after all components are created
            app.ShtatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shtatistics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShtatisticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShtatisticsUIFigure)
        end
    end
end