classdef Shtatistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShtatisticsUIFigure           matlab.ui.Figure
        ShtatisticsMenu               matlab.ui.container.Menu
        AboutShtatisticsMenu          matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        QuitShtatisticsMenu           matlab.ui.container.Menu
        FileMenu                      matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        ImportMenu                    matlab.ui.container.Menu
        SpreadsheetMenu               matlab.ui.container.Menu
        matFileMenu                   matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        DeleteMenu                    matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        FullScreenMenu                matlab.ui.container.Menu
        WorkspaceMenu                 matlab.ui.container.Menu
        RefreshWorkspaceMenu          matlab.ui.container.Menu
        ExtractVariablesMenu          matlab.ui.container.Menu
        FigureMenu                    matlab.ui.container.Menu
        NewFigureMenu                 matlab.ui.container.Menu
        NewPlotMenu                   matlab.ui.container.Menu
        NewPlotInANewFigureMenu       matlab.ui.container.Menu
        NewPlotInCurrentFigureMenu    matlab.ui.container.Menu
        TitleMenu                     matlab.ui.container.Menu
        LegendMenu                    matlab.ui.container.Menu
        LabelsMenu                    matlab.ui.container.Menu
        GridMenu                      matlab.ui.container.Menu
        GridMenu_2                    matlab.ui.container.Menu
        MinorGridMenu                 matlab.ui.container.Menu
        WindowMenu                    matlab.ui.container.Menu
        MainWindowMenu                matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        ImportButton                  matlab.ui.control.Button
        RefreshWorkspaceButton        matlab.ui.control.Button
        ExtractButton                 matlab.ui.control.Button
        GridLayout5                   matlab.ui.container.GridLayout
        NewFigureButton               matlab.ui.control.Button
        NewPlotInCurrentFigureButton  matlab.ui.control.Button
        WorkspaceUITable              matlab.ui.control.Table
        Tree                          matlab.ui.container.Tree
        CenterPanel                   matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        RightPanel                    matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        GridLayout7                   matlab.ui.container.GridLayout
        TitleButton                   matlab.ui.control.StateButton
        LegendButton                  matlab.ui.control.StateButton
        GridButton                    matlab.ui.control.StateButton
        LabelsButton                  matlab.ui.control.StateButton
        MinorGridButton               matlab.ui.control.StateButton
        AzimuthSpinnerLabel           matlab.ui.control.Label
        AzimuthSpinner                matlab.ui.control.Spinner
        ElevationSpinnerLabel         matlab.ui.control.Label
        ElevationSpinner              matlab.ui.control.Spinner
        Panel                         matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        CalledApps = struct
        SelectedRows
    end
    
    properties (Dependent)
        Whos
        ValidWorkspaceVars
    end
    
    properties (Constant)
        ValidPlotDataTypes = ["numeric", "logical", "cell", "struct", "categorical", "table", "timetable"]
    end
    
    methods   % set / get methods for dependent properties
        
        function value = get.Whos(~)
            value = evalin("base", "whos");
        end
        
        function value = get.ValidWorkspaceVars(app)
            value = struct([]);
            w = app.Whos;
            
            if ~isempty(w)
                value = struct("Name", '', "Class", '', "DataType", '', "Size", [], "Bytes", [], "Value", []);
                for r = 1 : length(w)
                     tmp = evalin("base", w(r).name);
                    try
                        validateattributes(tmp, app.ValidPlotDataTypes, {})
                    catch
                        continue
                    end
                    value(end+1).Value = tmp;
                    if isnumeric(tmp)
                        value(end).DataType = 'numeric';
                    else
                        value(end).DataType = w(r).class;
                    end
                    value(end).Name  = w(r).name;
                    value(end).Class = w(r).class;
                    value(end).Size  = w(r).size;
                    value(end).Bytes = w(r).bytes;
                    value(end).SizeChar = Matrix2SizeChar(value(end).Size);
                    try
                        validateattributes(value(end).Value, ["numeric", "logical", "char", "string"], {})
                        value(end).ValueChar = Matrix2ValueChar(value(end));
                    catch
                        value(end).ValueChar = [value(end).SizeChar ' ' value(end).Class];
                    end
                end
            end
            
            function value = Matrix2SizeChar(v)
                value = num2str(v(1));
                for i = 2 : length(v)
                    value = [value, 'x', num2str(v(i))];
                end
            end
            function value = Matrix2ValueChar(S)
                if (isnumeric(S.Value)) && (prod(S.Size) >= 1) && (prod(S.Size) <= 10)
                    str = string(S.Value);
                    tmp = join(str, ',', 2);
                    tmp = join(tmp, ';', 1);
                    value = char(tmp);
                elseif isscalar(S.Value)
                    value = char(S.Value);
                    switch S.Class
                        case "char"
                            value = ['''' value ''''];
                        case "string"
                            value = ['"' value '"'];
                    end
                else
                    size = Matrix2SizeChar(S.Size);
                    value = [size ' ' S.Class];
                end
            end
        end
        
    end
    
    methods (Access = public)
        
        function Plot(app)
            vars = app.ValidWorkspaceVars;
            T = struct2table(vars(app.SelectedRows), "AsArray", true);
            app.Tree.SelectedNodes.UserData = matlab.graphics.chart.primitive.Line("Parent",app.UIAxes, "LineStyle", 'none', "Marker", '.');
            str = ["XData", "YData", "ZData"];
            switch app.ValidWorkspaceVars.DataType
                
            end
            switch height(T)
                case 1
                case 2
                case 3
            end
            %             for i = 1 : length(app.SelectedRows)
            %                 app.Tree.SelectedNodes.UserData.(str(i)) = T.Value{i};
            %             end
            function PlotVectors(app)
                
            end
            function PlotTable(app)
                app.UIAxes.Visible = 'off';
                app.Panel.Visible = 'on';
                app.Tree.SelectedNodes.UserData = matlab.graphics.chart.StackedLineChart("Parent", app.Panel, "SourceTable", T.Value{:});
            end
        end
        
    end
    
    methods (Access = private)
        
    end
    
    events
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RefreshWorkspaceMenuSelected()
        end

        % Callback function: QuitShtatisticsMenu, ShtatisticsUIFigure
        function closeRequest(app, event)
            k = fieldnames(app.CalledApps);
            if ~isempty(k)
                for i = 1 : length(k)
                    eval(['app.CalledApps.' k{i} '.delete'])
                end
            end
            delete(app) %?
        end

        % Callback function: ImportButton, SpreadsheetMenu
        function SpreadsheetMenuSelected(app, event)
            uiimport('-file') %$
        end

        % Cell edit callback: WorkspaceUITable
        function WorkspaceUITableCellEdit(app, event)
            app.RefreshWorkspaceMenuSelected()
            
            indices = event.Indices;
            oldData = event.PreviousData;
            newData = event.NewData;
            
            switch app.WorkspaceUITable.ColumnName{indices(2)}
                case "Name"
                    if evalin("base", ['exist(''' oldData ''')'])
                        if ~any(strcmpi(newData, app.WorkspaceUITable.Data(:,indices(2))))   % Check that variable name is unique
                            assignin("base", newData, evalin("base", oldData))
                            evalin("base", ['clear ' oldData])
                        else
                            msg = sprintf("Couldn't change variable name from '%s' to '%s', since a varible of this name already exists.\n", oldData, newData);
                            warning(msg)
                            return
                        end
                    else   % The variable that was associated with <oldData> does not exist anymore
                        return
                    end
                otherwise   % Change this if you wish to let the user change more fields in the table
                    return
            end
            app.WorkspaceUITable.Data{indices(1), indices(2)} = newData;
            app.RefreshWorkspaceMenuSelected() %?
        end

        % Callback function: NewFigureButton, NewFigureMenu
        function NewFigureMenuSelected(app, event)
            app.Tree.Children(end+1) = uitreenode(app.Tree);
            app.Tree.Children(end).Text = ['Figure ' num2str(length(app.Tree.Children))];
            app.Tree.SelectedNodes   = app.Tree.Children(end);
            app.TreeSelectionChanged() %$
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Callback function: TitleButton, TitleMenu
        function TitleMenuSelected(app, event)
            oldState = app.TitleMenu.Checked;
            switch oldState
                case "off"
                    newState = 'on';
                    app.TitleButton.Value = 1;
                case "on"
                    newState = 'off';
                    app.TitleButton.Value = 0;
            end
            app.TitleMenu.Checked = newState;
            app.UIAxes.Title.Visible = newState; %$
        end

        % Callback function: LegendButton, LegendMenu
        function LegendMenuSelected(app, event)
            oldState = app.TitleMenu.Checked;
            switch oldState
                case "off"
                    newState = 'on';
                    app.LegendButton.Value = 1;
                case "on"
                    newState = 'off';
                    app.LegendButton.Value = 0;
            end
            app.LegendMenu.Checked = newState;
            app.UIAxes.Legend.Visible = newState; %?
        end

        % Callback function: LabelsButton, LabelsMenu
        function LabelsMenuSelected(app, event)
            oldState = app.LabelsMenu.Checked;
            switch oldState
                case "off"
                    newState = 'on';
                    app.LabelsButton.Value = 1;
                case "on"
                    newState = 'off';
                    app.LabelsButton.Value = 0;
            end
            app.LabelsMenu.Checked = newState;
            app.UIAxes.XLabel.Visible = newState;
            app.UIAxes.YLabel.Visible = newState;
            app.UIAxes.ZLabel.Visible = newState; %$
        end

        % Callback function: GridButton, GridMenu_2
        function GridMenu_2Selected(app, event)
            oldState = app.GridMenu_2.Checked;
            switch oldState
                case "off"
                    newState = 'on';
                    app.GridButton.Value = 1;
                case "on"
                    newState = 'off';
                    app.GridButton.Value = 0;
            end
            app.GridMenu_2.Checked = newState;
            app.UIAxes.XGrid = newState;
            app.UIAxes.YGrid = newState;
            app.UIAxes.ZGrid = newState; %$
        end

        % Callback function: MinorGridButton, MinorGridMenu
        function MinorGridMenuSelected(app, event)
            oldState = app.MinorGridMenu.Checked;
            switch oldState
                case "off"
                    newState = 'on';
                    app.MinorGridButton.Value = 1;
                case "on"
                    newState = 'off';
                    app.MinorGridButton.Value = 0;
            end
            app.MinorGridMenu.Checked = newState;
            app.UIAxes.XMinorGrid = newState;
            app.UIAxes.YMinorGrid = newState;
            app.UIAxes.ZMinorGrid = newState; %$
        end

        % Value changing function: AzimuthSpinner
        function AzimuthSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if ~isnumeric(changingValue)
                changingValue = str2double(changingValue);
            end
            app.UIAxes.View = [changingValue, app.UIAxes.View(end)]; %$
        end

        % Value changing function: ElevationSpinner
        function ElevationSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if ~isnumeric(changingValue)
                changingValue = str2double(changingValue);
            end
            app.UIAxes.View = [app.UIAxes.View(1), changingValue]; %$
        end

        % Window key release function: ShtatisticsUIFigure
        function ShtatisticsUIFigureWindowKeyRelease(app, event)
            key = event.Key;
            modifier = event.Modifier;
            % % % % % % % % % % % % % % % % % % % % % % %
        end

        % Cell selection callback: WorkspaceUITable
        function WorkspaceUITableCellSelection(app, event)
            indices = event.Indices;
            app.SelectedRows = unique(indices(:,1), "stable"); %$
        end

        % Callback function: ExtractButton, ExtractVariablesMenu
        function ExtractVariablesMenuSelected(app, event)
            % % % % % % % % % % %
        end

        % Menu selected function: NewPlotInANewFigureMenu
        function NewPlotInANewFigureMenuSelected(app, event)
            app.Tree.SelectedNodes = [];
            app.NewPlotInCurrentFigureMenuSelected() %$
        end

        % Callback function: NewPlotInCurrentFigureButton, 
        % NewPlotInCurrentFigureMenu
        function NewPlotInCurrentFigureMenuSelected(app, event)
            if isempty(app.Tree.SelectedNodes)
                app.NewFigureMenuSelected()
            end
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes.Parent.Type == "uitree"
                % The selected node is a figure
                selectedNodes.Children(end+1) = uitreenode(selectedNodes);
                selectedNodes.Children(end).Text = ['Plot ' num2str(length(selectedNodes.Children))];
                selectedNodes.expand()
                app.Tree.SelectedNodes   = selectedNodes.Children(end);
            elseif selectedNodes.Parent.Parent.Type == "uitree"
                % The selected node is a plot
                parentFigure = selectedNodes.Parent;
                parentFigure.Children(end+1) = uitreenode(parentFigure);
                parentFigure.Children(end).Text = ['Plot ' num2str(length(parentFigure.Children))];
                parentFigure.expand()
                app.Tree.SelectedNodes   = parentFigure.Children(end);
            else
                error('Method app.NewPlotInCurrentFigureMenu() has run into an ERROR.')
            end
            app.TreeSelectionChanged()
            
            % Check if user has selected variables to plot
            if ~isempty(app.SelectedRows) && length(app.SelectedRows) <= 3
                app.Plot()
            end %$
        end

        % Callback function: RefreshWorkspaceButton, 
        % RefreshWorkspaceMenu
        function RefreshWorkspaceMenuSelected(app, event)
            columns = string( app.WorkspaceUITable.ColumnName );
            vars = app.ValidWorkspaceVars;
            app.WorkspaceUITable.Data = cell(length(vars), length(columns));
            if ~isempty(vars)
                app.WorkspaceUITable.RowName = {vars(:).Name};
                if any(strcmpi(columns, "Class"))
                    app.WorkspaceUITable.Data(:, strcmpi(columns, "Class")) = {vars(:).Class};
                end
                if any(strcmpi(columns, "Name"))
                    app.WorkspaceUITable.Data(:, strcmpi(columns, "Name")) = {vars(:).Name};
                end
                if any(strcmpi(columns, "Bytes"))
                    app.WorkspaceUITable.Data(:, strcmpi(columns, "Bytes")) = {vars(:).Bytes};
                end
                if any(strcmpi(columns, "Size"))
                    app.WorkspaceUITable.Data(:, strcmpi(columns, "Size")) = {vars(:).SizeChar};
                end
                if any(strcmpi(columns, "Value"))
                    app.WorkspaceUITable.Data(:, strcmpi(columns, "Value")) = {vars(:).ValueChar};
                end
            end %?
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ShtatisticsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {1080, 1080, 1080};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {1080, 1080};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {381, '1x', 1209};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShtatisticsUIFigure = uifigure('Visible', 'off');
            app.ShtatisticsUIFigure.Name = 'Shtatistics';
            app.ShtatisticsUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.ShtatisticsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ShtatisticsUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @ShtatisticsUIFigureWindowKeyRelease, true);
            app.ShtatisticsMenu = uimenu(app.ShtatisticsUIFigure);
            app.ShtatisticsMenu.Text = 'Shtatistics';
            app.AboutShtatisticsMenu = uimenu(app.ShtatisticsMenu);
            app.AboutShtatisticsMenu.Text = 'About Shtatistics...';
            app.PreferencesMenu = uimenu(app.ShtatisticsMenu);
            app.PreferencesMenu.Separator = 'on';
            app.PreferencesMenu.Accelerator = '''';
            app.PreferencesMenu.Text = 'Preferences...';
            app.QuitShtatisticsMenu = uimenu(app.ShtatisticsMenu);
            app.QuitShtatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @closeRequest, true);
            app.QuitShtatisticsMenu.Separator = 'on';
            app.QuitShtatisticsMenu.Accelerator = 'q';
            app.QuitShtatisticsMenu.Text = 'Quit Shtatistics';
            app.FileMenu = uimenu(app.ShtatisticsUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Separator = 'on';
            app.ImportMenu.Text = 'Import';
            app.SpreadsheetMenu = uimenu(app.ImportMenu);
            app.SpreadsheetMenu.MenuSelectedFcn = createCallbackFcn(app, @SpreadsheetMenuSelected, true);
            app.SpreadsheetMenu.Accelerator = 'i';
            app.SpreadsheetMenu.Text = 'Spreadsheet';
            app.matFileMenu = uimenu(app.ImportMenu);
            app.matFileMenu.Text = '.mat File';
            app.EditMenu = uimenu(app.ShtatisticsUIFigure);
            app.EditMenu.Text = 'Edit';
            app.DeleteMenu = uimenu(app.EditMenu);
            app.DeleteMenu.Text = 'Delete';
            app.ViewMenu = uimenu(app.ShtatisticsUIFigure);
            app.ViewMenu.Text = 'View';
            app.FullScreenMenu = uimenu(app.ViewMenu);
            app.FullScreenMenu.Text = 'Full Screen';
            app.WorkspaceMenu = uimenu(app.ShtatisticsUIFigure);
            app.WorkspaceMenu.Text = 'Workspace';
            app.RefreshWorkspaceMenu = uimenu(app.WorkspaceMenu);
            app.RefreshWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshWorkspaceMenuSelected, true);
            app.RefreshWorkspaceMenu.Accelerator = 'r';
            app.RefreshWorkspaceMenu.Text = 'Refresh Workspace';
            app.ExtractVariablesMenu = uimenu(app.WorkspaceMenu);
            app.ExtractVariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractVariablesMenuSelected, true);
            app.ExtractVariablesMenu.Separator = 'on';
            app.ExtractVariablesMenu.Accelerator = 'e';
            app.ExtractVariablesMenu.Text = 'Extract Variables';
            app.FigureMenu = uimenu(app.ShtatisticsUIFigure);
            app.FigureMenu.Text = 'Figure';
            app.NewFigureMenu = uimenu(app.FigureMenu);
            app.NewFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @NewFigureMenuSelected, true);
            app.NewFigureMenu.Text = 'New Figure';
            app.NewPlotMenu = uimenu(app.FigureMenu);
            app.NewPlotMenu.Text = 'New Plot';
            app.NewPlotInANewFigureMenu = uimenu(app.NewPlotMenu);
            app.NewPlotInANewFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPlotInANewFigureMenuSelected, true);
            app.NewPlotInANewFigureMenu.Text = 'In A New Figure';
            app.NewPlotInCurrentFigureMenu = uimenu(app.NewPlotMenu);
            app.NewPlotInCurrentFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPlotInCurrentFigureMenuSelected, true);
            app.NewPlotInCurrentFigureMenu.Text = 'In Current Figure';
            app.TitleMenu = uimenu(app.FigureMenu);
            app.TitleMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleMenuSelected, true);
            app.TitleMenu.Separator = 'on';
            app.TitleMenu.Checked = 'on';
            app.TitleMenu.Text = 'Title';
            app.LegendMenu = uimenu(app.FigureMenu);
            app.LegendMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendMenuSelected, true);
            app.LegendMenu.Enable = 'off';
            app.LegendMenu.Text = 'Legend';
            app.LabelsMenu = uimenu(app.FigureMenu);
            app.LabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @LabelsMenuSelected, true);
            app.LabelsMenu.Checked = 'on';
            app.LabelsMenu.Text = 'Labels';
            app.GridMenu = uimenu(app.FigureMenu);
            app.GridMenu.Checked = 'on';
            app.GridMenu.Text = 'Grid';
            app.GridMenu_2 = uimenu(app.GridMenu);
            app.GridMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GridMenu_2Selected, true);
            app.GridMenu_2.Checked = 'on';
            app.GridMenu_2.Text = 'Grid';
            app.MinorGridMenu = uimenu(app.GridMenu);
            app.MinorGridMenu.MenuSelectedFcn = createCallbackFcn(app, @MinorGridMenuSelected, true);
            app.MinorGridMenu.Checked = 'on';
            app.MinorGridMenu.Text = 'Minor Grid';
            app.WindowMenu = uimenu(app.ShtatisticsUIFigure);
            app.WindowMenu.Text = 'Window';
            app.MainWindowMenu = uimenu(app.WindowMenu);
            app.MainWindowMenu.Accelerator = '1';
            app.MainWindowMenu.Text = 'Main Window';
            app.HelpMenu = uimenu(app.ShtatisticsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.ShtatisticsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Workspace';
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.Image.Tooltip = {'Shtatistics'};
            app.Image.ImageSource = 'business-color_stock_icon-icons.com_53431.png';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ImportButton = uibutton(app.GridLayout4, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @SpreadsheetMenuSelected, true);
            app.ImportButton.Icon = 'Import_A-512.png';
            app.ImportButton.Text = '';
            app.RefreshWorkspaceButton = uibutton(app.GridLayout4, 'push');
            app.RefreshWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshWorkspaceMenuSelected, true);
            app.RefreshWorkspaceButton.Icon = '1024px-OOjs_UI_icon_reload.svg.png';
            app.RefreshWorkspaceButton.Text = '';
            app.ExtractButton = uibutton(app.GridLayout4, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractVariablesMenuSelected, true);
            app.ExtractButton.Icon = 'extract-512.png';
            app.ExtractButton.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.NewFigureButton = uibutton(app.GridLayout5, 'push');
            app.NewFigureButton.ButtonPushedFcn = createCallbackFcn(app, @NewFigureMenuSelected, true);
            app.NewFigureButton.Icon = 'chart+business+graph+business+growth+graph+icon-1320184002415483307.png';
            app.NewFigureButton.Text = '';
            app.NewPlotInCurrentFigureButton = uibutton(app.GridLayout5, 'push');
            app.NewPlotInCurrentFigureButton.ButtonPushedFcn = createCallbackFcn(app, @NewPlotInCurrentFigureMenuSelected, true);
            app.NewPlotInCurrentFigureButton.Icon = '19-512.png';
            app.NewPlotInCurrentFigureButton.Text = '';
            app.WorkspaceUITable = uitable(app.GridLayout2);
            app.WorkspaceUITable.ColumnName = {'Class'; 'Name'; 'Size'; 'Value'};
            app.WorkspaceUITable.RowName = {''};
            app.WorkspaceUITable.ColumnSortable = [true true true false];
            app.WorkspaceUITable.ColumnEditable = [false true false false];
            app.WorkspaceUITable.CellEditCallback = createCallbackFcn(app, @WorkspaceUITableCellEdit, true);
            app.WorkspaceUITable.CellSelectionCallback = createCallbackFcn(app, @WorkspaceUITableCellSelection, true);
            app.WorkspaceUITable.Tooltip = {'Workspace'};
            app.Tree = uitree(app.GridLayout2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.Tree.Tooltip = {'Figures and plots'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Property Inspector';
            app.GridLayout8 = uigridlayout(app.CenterPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.TitleButton = uibutton(app.GridLayout7, 'state');
            app.TitleButton.ValueChangedFcn = createCallbackFcn(app, @TitleMenuSelected, true);
            app.TitleButton.Icon = 'letter-t-png-2.png';
            app.TitleButton.Text = '';
            app.TitleButton.Value = true;
            app.LegendButton = uibutton(app.GridLayout7, 'state');
            app.LegendButton.ValueChangedFcn = createCallbackFcn(app, @LegendMenuSelected, true);
            app.LegendButton.Enable = 'off';
            app.LegendButton.Text = 'Legend';
            app.GridButton = uibutton(app.GridLayout7, 'state');
            app.GridButton.ValueChangedFcn = createCallbackFcn(app, @GridMenu_2Selected, true);
            app.GridButton.Text = 'Grid';
            app.GridButton.Value = true;
            app.LabelsButton = uibutton(app.GridLayout7, 'state');
            app.LabelsButton.ValueChangedFcn = createCallbackFcn(app, @LabelsMenuSelected, true);
            app.LabelsButton.Text = 'Labels';
            app.LabelsButton.Value = true;
            app.MinorGridButton = uibutton(app.GridLayout7, 'state');
            app.MinorGridButton.ValueChangedFcn = createCallbackFcn(app, @MinorGridMenuSelected, true);
            app.MinorGridButton.Text = 'Minor Grid';
            app.MinorGridButton.Value = true;
            app.AzimuthSpinnerLabel = uilabel(app.GridLayout7);
            app.AzimuthSpinnerLabel.Text = 'Azimuth';
            app.AzimuthSpinner = uispinner(app.GridLayout7);
            app.AzimuthSpinner.ValueChangingFcn = createCallbackFcn(app, @AzimuthSpinnerValueChanging, true);
            app.AzimuthSpinner.Limits = [-180 180];
            app.ElevationSpinnerLabel = uilabel(app.GridLayout7);
            app.ElevationSpinnerLabel.Text = 'Elevation';
            app.ElevationSpinner = uispinner(app.GridLayout7);
            app.ElevationSpinner.ValueChangingFcn = createCallbackFcn(app, @ElevationSpinnerValueChanging, true);
            app.ElevationSpinner.Limits = [-180 180];
            app.ElevationSpinner.Value = 90;
            app.Panel = uipanel(app.GridLayout6);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            % Show the figure after all components are created
            app.ShtatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Shtatistics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShtatisticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShtatisticsUIFigure)
        end
    end
end